classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Image                  matlab.ui.control.Image
        Label                  matlab.ui.control.Label
        Label_2                matlab.ui.control.Label
        ThedesiredsourceDOAwithMUSICisLabel  matlab.ui.control.Label
        TheinterferingDOAwithMUSICisLabel  matlab.ui.control.Label
        WindowLengthKnobLabel  matlab.ui.control.Label
        WindowLengthKnob       matlab.ui.control.Knob
        KnobLabel              matlab.ui.control.Label
        Knob                   matlab.ui.control.Knob
        Label_3                matlab.ui.control.Label
        Spinner                matlab.ui.control.Spinner
        StartButton            matlab.ui.control.Button
        Button                 matlab.ui.control.StateButton
        Button_2               matlab.ui.control.StateButton
        RuningLampLabel        matlab.ui.control.Label
        RuningLamp             matlab.ui.control.Lamp
        UIAxes                 matlab.ui.control.UIAxes
    end

    properties (Access = private)
        Pax % Polar axes
       
    end
        
    
    methods (Access = private)
        
        function UpdatePlot(app,n,~)
            
            
            device=audioDeviceReader("SampleRate",16000,"Device","麦克风 (USB YDB01 Audio Effect)","NumChannels",4);
            
            fs=16000;
            app.RuningLamp.Color=[0,1,0];
            
            for i=1:n
                
                %% 开关
%                 if app.Switch.Value=="Off"
%                     break
%                 end
                tic;
                X=[];     
                
                while toc<3
                    
                    try
                        au3=device();
                    catch
                        break
                    end
                    X=[X;au3];
                end
                
                try
                    X(:,[1,2])=X(:,[2,1]);
                    X(:,[3,4])=X(:,[4,3]);
                    X(:,[1,2,3,4])=X(:,[4,3,2,1]);
                catch
                    break
                end


                N1=length(X);
                
                
                fra_per_win = app.WindowLengthKnob.Value;                               %每个window中frame的数量
                n_window = floor(N1/fra_per_win);                        %window个数
                %每个window wlen个采样点
                N=n_window*fra_per_win;
                
                
                %% Plot waveform
                
        
                %% Array setup
                
                [Frame,nSensors] = size(X);
                J = nSensors;                                      % number of sensors
                dx = 0.025;                                        % inter-sensor distance in x direction (m)
                dy = 0;                                            % sensor distance in y direction (m)
                c = 340;                                           % sound velocity  (m)
                n_source = 2;                                      % number of sources
                Index = linspace(0,J-1,J);
                p = (-(J-1)/2 + Index.') * [dx dy];                % sensor position
                %     %% Plot sensor positions
                %
                %     linspec = {'rx','MarkerSize',12,'LineWidth',2};
                %     figure
                %     plot(p(:,1),p(:,2),linspec{:});
                %     title('Sensor positions');
                %     xlabel('x position in meters');
                %     ylabel('y position in meters');
                %     disp('The four microphones are ready !');
                %% STFT（Short Time Fourier Transformation）


                % ds = 1/fs;
                % t = 0:ds:X_len/fs-ds;
                matrix_STFT = zeros([fra_per_win,n_window,nSensors]); %创建一个 160*300*4的零矩阵
                
                for n = 1:nSensors                                      %将每一个48000*4的矩阵分割为160*300*4
                    for x = 1:n_window
                        matrix_STFT(:,x,n) = X(fra_per_win*(x-1)+(1:fra_per_win),n);
                    end
                end
                % for n = 1:n_window
                %     matrix_STFT_result(:,n,:) = fft(permute(matrix_STFT(:,n,:),[1,3,2]));
                % end
                matrix_STFT_result = fft(matrix_STFT);
                matrix_STFT_result = matrix_STFT_result(1:(fra_per_win/2+1),:,:);
                %% Evaluate the general denominator
                
                stride = 0.1;                                           % determine the angular resolution(deg)
                theta = -90:stride:90;                                  % grid
                denominator_temp = zeros(size(theta,2),1);
                linear_frequency_distribution = linspace(0,fs,fra_per_win);
                for n = 1:size(matrix_STFT_result,1)               %79行一行一行取
                    temping_matrix = permute(matrix_STFT_result(n,:,:),[2 3 1]) ;
                    transpose_matrix = temping_matrix.';
                    adding_matrix = transpose_matrix*transpose_matrix';
                    
                    R_x = (1/n_window).*adding_matrix;                             % autocorrelation estimate
                    v = [sin(theta*pi/180);-cos(theta*pi/180)];            % direction vector
                    a_theta = exp(-1j*2*pi*linear_frequency_distribution(n)*(p*v)./c);                  % steer vector
                    
                    % implement eigen-decomposition
                    [V,D] = eig(R_x);
                    for x = 1:J
                        temp(1,x)=D(x,x);
                    end
                    V(J+1,:) = temp(1,:);
                    V_temp = sortrows(V.',J+1);
                    V_temp = V_temp.';
                    V = V_temp(1:J,:);
                    Un = V(:,1:J-n_source);
                    denominator_temp = denominator_temp+diag(a_theta'*(Un*Un')*a_theta);
                    
                end
                %% DoA estimation (MUSIC)                                    % noise subspace (columns are eigenvectors), size: J*(J-n_source)
                P_sm = 1./denominator_temp;          % pseudo music power
                %% Plot the MUSIC pseudo power spectrum
                
                
                linspec = {'b-','LineWidth',2};
                if app.Button.Value==true
                    plot(app.UIAxes,theta, 10*log10(abs(P_sm)), linspec{:});
                    drawnow;
                end
                
                if app.Button_2.Value==true
                    polarplot(app.Pax,theta*pi/180,10*log10(abs(P_sm))+app.Knob.Value)
                end
                
                %% Find the local maximum and visualization
                P_middle = abs(P_sm(2:end-1));
                P_front = abs(P_sm(1:end-2));
                P_back = abs(P_sm(3:end));
                logic_front = (P_middle - P_front)>0;
                logic_back = (P_middle - P_back)>0;
                logic = logic_front & logic_back;
                P_middle(~logic) = min(P_middle);
                P_local = [abs(P_sm(1));P_middle;abs(P_sm(end))];
                [~,doa_Idx] = maxk(P_local,n_source);
                doa = theta(doa_Idx);
                [~,minIdx] = min(abs(doa));
                doa_source = doa(minIdx);
                [~,maxIdx] = max(abs(doa));
                interfer = doa(maxIdx);
                try
                    app.Label.Text=[num2str(doa_source),' deg'];
                    app.Label_2.Text=[num2str(interfer),' deg'];
                catch
                    return
                end
                
            end
            app.RuningLamp.Color=[1,1,1];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            title(app.UIAxes,'MUSIC pseudo power spectrum')
            xlabel(app.UIAxes,'Angle in [degrees]');
            ylabel(app.UIAxes,'Power spectrum in [dB]');
            xlim(app.UIAxes,[-90,90]);
            
            app.RuningLamp.Color=[1,1,1];
            
            
            app.Pax = polaraxes(app.UIFigure);
            app.Pax.Units = 'pixels';
            app.Pax.Position = [450 230 150 150];

        end

        % Callback function
        function SwitchValueChanged(app, event)
            value=app.Switch.Value;
            UpdatePlot(app,value)
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            UpdatePlot(app,app.Spinner.Value)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.Enable = 'off';
            app.Image.ImageSource = '未标题-2.png';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.ThedesiredsourceDOAwithMUSICisLabel = uilabel(app.UIFigure);
            app.ThedesiredsourceDOAwithMUSICisLabel.Text = {'The desired '; 'source DOA '; 'with MUSIC is:'; ''};
            app.TheinterferingDOAwithMUSICisLabel = uilabel(app.UIFigure);
            app.TheinterferingDOAwithMUSICisLabel.Text = {'The interfering '; 'DOA with '; 'MUSIC is:'};
            app.WindowLengthKnobLabel = uilabel(app.UIFigure);
            app.WindowLengthKnobLabel.Text = 'Window Length';
            app.WindowLengthKnob = uiknob(app.UIFigure, 'continuous');
            app.WindowLengthKnob.Limits = [50 2000];
            app.WindowLengthKnob.Value = 200;
            app.KnobLabel = uilabel(app.UIFigure);
            app.KnobLabel.Text = '极坐标图增益';
            app.Knob = uiknob(app.UIFigure, 'continuous');
            app.Knob.Limits = [0 50];
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = {''; ''};
            app.Spinner = uispinner(app.UIFigure);
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = {'Start'; ''};
            app.Button = uibutton(app.UIFigure, 'state');
            app.Button.Text = '功率图';
            app.Button_2 = uibutton(app.UIFigure, 'state');
            app.Button_2.Text = {'极坐标功率图'; ''};
            app.RuningLampLabel = uilabel(app.UIFigure);
            app.RuningLampLabel.Text = 'Runing';
            app.RuningLamp = uilamp(app.UIFigure);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end